classdef InerterAppSerial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InerterSpecsButton              matlab.ui.control.Button
        InerterTypeDropDown             matlab.ui.control.DropDown
        InerterTypeDropDownLabel        matlab.ui.control.Label
        FunctionGeneratorSwitch         matlab.ui.control.Switch
        FunctionGeneratorSwitchLabel    matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        CalibrateLoadCellButton         matlab.ui.control.Button
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        SaveDataButton                  matlab.ui.control.Button
        CalculatedInertanceEditField_2  matlab.ui.control.NumericEditField
        CalculatedInertancekgEditFieldLabel_2  matlab.ui.control.Label
        TimesSlider                     matlab.ui.control.Slider
        TimesSliderLabel                matlab.ui.control.Label
        StartDataCollectionButton       matlab.ui.control.StateButton
        ExperimentalInertanceEditField  matlab.ui.control.NumericEditField
        ExperimentalInertanceLabel      matlab.ui.control.Label
        PerformInertanceCalculationButton  matlab.ui.control.Button
        FourierTransformPlot            matlab.ui.control.UIAxes
        ForcePlot                       matlab.ui.control.UIAxes
        AccPlot                         matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)
        % Data Collection
        forceDataArray % Array storing force data from load cell
        accDataArray % Array storing accelerometer data
        accTimeArray % Array storing time data from accelerometer/daq
        forceTimeArray % Array storing time data from arduino load cell

        % Calculated Data
        F_exp % Experimentally determined force value
        a_exp % Experimentally determined acceleration value
        b_exp % Experimental b value
        b_theo % Theoretical b value
        forceFD % Force fft
        accFD % Acceleration fft

        % Inerter Specs
        InerterType % Type of Inerter in test rig
        OuterRadii % Outer radius of gear (Gear Inerter)
        InnerRadii % Inner radius of gear (Gear Inerter)
        InerterMass % Mass of interter (Gear Inerter)
        numGears % Number of gears (Gear Inerter)
        MassRack % Mass of rack (Chad Inerter)
        MomentInertia % Moment of inertia of flywheels (Chad Inerter)
        GearRadius % Radius of gear (Chad Inerter)

        % Arduino
        a % Arduino connection
        LoadCell % Load cell property
        Tare % Calibration variable
        KnownWeight % Known calibration weight
        Scale % Calibration sale factor
        serialObj % Serial comunication object

        % Daq
        d % Daq connection
        ch % Daq channel

        % Function Generator
        v % Visa
        FreqVal % Desired Frequency
        AmpVal % Desired Amplitude

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PerformInertanceCalculationButton
        function CalculateInertance(app, event)
            % This function performs fft on force and acceleration data,
            % calculates theoretical inertance values based on inerter
            % type, and displays the data on the GUI.

            % Only generates experimental results if data is present
            if ~isempty(app.forceDataArray) && ~isempty(app.accDataArray)

                % Frequency domain analysis
                % Divide by number of samples and multiply by 2 to correct
                % amplitude after taking fft
                app.forceFD = 2 * (abs(fft(app.forceDataArray))./length(app.forceFD));  % Force data in frequency domain
                app.accFD = 2 * (abs(fft(app.accDataArray))./length(app.accFD));        % Acceleration data in frequency domain
                app.F_exp = max(app.forceFD);    % Experimentally determined F value taking max of fourier transform
                app.a_exp = max(app.accFD);      % Experimentally determined a value taking max of fourier transform

                % Calculating experimental inertance value Inertance formula: b = F/a
                app.b_exp = real(app.F_exp/app.a_exp); % Use real portion of fourier tansform, ignore imaginary parts
            else
                app.b_exp = 0; % Set experimental inertance to 0 if no data is present
            end


            % Calculating theoretical inertance value
            switch app.InerterType % Cases based on inerter type chosen from dropdown
                case 1 % Gear Inerter
                    R = app.OuterRadii;        % Outer gear radius (m)
                    r = app.InnerRadii;        % Inner gear radius (m)
                    m = app.InerterMass;       % Mass of inerter (kg)
                    n = app.numGears;          % Number of gears in train
                    app.b_theo = (R^2/r^2)^n * m; % Theoretical equation for gear inerter


                case 2 % Chad Inerter (Dragster rack and pinion)
                    mRack = app.MassRack; % Mass of rack (kg)
                    J = app.MomentInertia; % Moment of Inertia for flywheel (kg*m^2)
                    r = app.GearRadius; % Radius of gear
                    app.b_theo = (mRack + (J / (r ^ 2))) ; % Theoretical equation for chad inerter
            end

            % Displaying inertance results to GUI
            if isnan(app.b_exp) % Error message if experimental data gives nan result
                fig = uifigure;
                msg = "Inertance calculation error. Please collect new data and try again.";
                title = "B-value Calculation Error";

                % Selection buttons for error message
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Ok"], ...
                    "DefaultOption",1);

                % Delete figure when closing error message
                if strcmp("Ok", selection)
                    delete(fig);
                    return;
                end
            else
                % Display calculated values and data plots
                app.ExperimentalInertanceEditField.Value = app.b_exp;
                app.CalculatedInertanceEditField_2.Value = app.b_theo;

                % Display foruier transform plots for force and acceleration
                hold(app.FourierTransformPlot, 'on')
                yyaxis(app.FourierTransformPlot, 'left')
                plot(app.FourierTransformPlot, abs(app.forceFD) , 'r-');
                yyaxis(app.FourierTransformPlot, 'right')
                plot(app.FourierTransformPlot, abs(app.accFD) , 'b-');
                legend(app.FourierTransformPlot, 'Force', 'Acceleration')
                xlim(app.FourierTransformPlot, [0 100]);
                hold(app.FourierTransformPlot, 'off')
            end
        end

        % Value changed function: StartDataCollectionButton
        function StartDataCollectionButtonValueChanged(app, event)
            % This function collects acceleration and force data when
            % directed by the user.

            % Setup daq for acceleration collection
            if isempty(app.d)
                app.d = daq("ni"); % Create daq object
                app.ch = addinput(app.d, "cDAQ1Mod1", "ai0", "Accelerometer"); % Add input for accelerometer
                app.d.Rate = 2048; % Data collection rate of accelerometer
                app.ch.Sensitivity = 0.00999; % Sensitivity as stated from accelerometer calibration data card
            end

            % Initialize Data Arrays
            app.forceDataArray = []; % force array
            app.forceTimeArray = []; % force time array
            app.accDataArray = []; % acceleration array
            app.accTimeArray = []; % acceleration time array

            % Check for load cell calibration
            if isempty(app.Scale)
                % Error message if not calibrated
                fig = uifigure;
                msg = "Must calibrate before collecting data.";
                title = "Data Collection Error";

                % Selection options for calibration error message
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Ok"], ...
                    "DefaultOption",1);

                % Delete figure when exiting calibration error message
                if strcmp("Ok", selection)
                    delete(fig);
                    return;
                end
            end

            % Message to display when data is being collected
            fig = uifigure;
            fig.Position = [500, 500, 370, 220];
            uiprogressdlg(fig, 'Title', "Data Collection", 'Message', "Collecting Force and Acceleration Data.")


            % Start data collection

            % Start timer used to seperate force and acceleration data collection
            flush(app.serialObj);
            tStart = tic;

            % Collect force data for time specified by user on slider
            while toc(tStart) < app.TimesSlider.Value

                app.forceDataArray = [app.forceDataArray str2double(readline(app.serialObj))];
                app.forceTimeArray = [app.forceTimeArray toc(tStart)];

            end

            % Collect acceleration data for time specified by user on slider
            start(app.d, "duration", app.TimesSlider.Value); % Starts daq data collection for time specified by slider

            % Collect data from daq
            [data, timestamps] = read(app.d, seconds(app.TimesSlider.Value), "OutputFormat", "Matrix");
            % Store time from daq
            app.accTimeArray = [app.accTimeArray; timestamps];
            % Store acceleration data collection
            app.accDataArray = [app.accDataArray; data];

            % Use calibration variables to calculate force
            app.forceDataArray = 9.81 .* (app.forceDataArray - app.Tare) ./ app.Scale;

            % Plots for force and acceleration data
            plot(app.ForcePlot, app.forceTimeArray ,app.forceDataArray, 'k-');
            plot(app.AccPlot, app.accTimeArray, app.accDataArray, 'k-');

            % delete figure for data collection notification
            delete(fig)

        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            % Check if data has been collected
            if isempty(app.forceDataArray)
                % alert user no data to save
                fig = uifigure;
                msg = "No data collected.";
                title = "Save Error";

                % Selection options for save error message
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Ok"], ...
                    "DefaultOption",1);

                % Delete figure when exiting error message
                if strcmp("Ok", selection)
                    delete(fig);
                    return;
                end
            end

            % Save data to specified file
            if isfile(app.FilenameEditField.Value)

                % If the file the user is trying to save to exist
                % Check if user wants to overwrite data on old file
                fig = uifigure;
                msg = "Saving these changes will overwrite previous changes.";
                title = "Confirm Save";

                % Options to overwrite data or cancel save process
                selection = uiconfirm(fig,msg,title, ...
                    "Options",["Overwrite","Cancel"], ...
                    "DefaultOption",1,"CancelOption",2);

                % Delete figure when canceling save process
                if strcmp("Cancel", selection)
                    delete(fig)
                    return;
                end
            end

            % Create empty array to store data
            % Determine size of array by basing it on longest set of data
            if(size(app.forceDataArray, 2) <  size(app.accDataArray,1))

                % Initialize data array that is the size of the number of
                % acceleration data points
                T_Data = zeros(size(app.accDataArray,1),8);

            else
                % Initialize data array that is the size of the number of
                % force data points
                T_Data = zeros(size(app.forceDataArray,1),8); % Initialize data array

            end

            % Assign and format data to columns
            T_Data(1:size(app.forceDataArray, 2), 1) = app.forceDataArray';
            T_Data(1:size(app.forceDataArray, 2), 2) = app.forceTimeArray';
            T_Data(1:size(app.accDataArray, 1), 3) = app.accDataArray;
            T_Data(1:size(app.accDataArray, 1), 4) = app.accTimeArray;

            % Set inertance (b) values to zero if the theoretical was not
            % calculated by user
            if isempty(app.b_theo)
                app.F_exp = 0;
                app.a_exp = 0;
                app.b_theo = 0;
                app.b_exp = 0;
            end

            % Assign and format b values data to columns
            T_Data(1, 5) = app.F_exp';
            T_Data(1:size(app.forceFD, 2), 6) = abs(app.forceFD)';
            T_Data(1, 7) = app.a_exp';
            T_Data(1:size(app.accFD, 1), 8) = abs(app.accFD);
            T_Data(1, 9) = app.b_theo';
            T_Data(1, 10) = app.b_exp';

            % Create table
            T = array2table(T_Data,'VariableNames', ...
                {'Force', 'Force Time', ...
                'Acceleration', 'Acceleration Time',...
                'Force FFT Max', 'Force FFT', 'Acceleration FFT Max',...
                'Acceleration FFT', 'Theoretical B value','Experimental B value'});

            % save table to the filepath directed by the user
            writetable(T, app.FilenameEditField.Value)

        end

        % Button pushed function: CalibrateLoadCellButton
        function CalibrateLoadCell(app, event)
            % This function is used to calibrate the load cell, store the tare
            % and scale values to an excell sheet, and allow the user to
            % recalibrate or use previously collected calibration data.

            try
                port = "COM7"; % Change this to appropriate arduino port
                app.serialObj = serialport(port, 115200); % Serial port and baud rate from arduino script
                configureTerminator(app.serialObj, "LF"); % Terminator is a line return
                flush(app.serialObj); % Clear serial data

            catch
                % Error message if Arduino is not connected to the computer
                fig = uifigure;
                uialert(fig, "Arduino not connected.", 'Invalid Connection')
                delete(fig);
                return
            end

            % Message to ask user to perform a new calibration or use
            % previously collected tare and scale values
            fig = uifigure;
            fig.Position = [500, 500, 370, 220];
            msg = "Perform new calibration or use previous calibration data?";
            title = "Calibration";

            % Selections for begining new calibration or loading old values
            selection = uiconfirm(fig,msg,title, ...
                "Options",["New Calibration","Load Previous Data"], ...
                "DefaultOption",1,"CancelOption",2);

            % Retrieve previous calibration data from excel sheet
            if strcmp(selection, "Load Previous Data")
                CalVals = readtable("Calibration.csv");
                % Store Tare and Scale properties as previous values
                app.Tare = CalVals{1,1};
                app.Scale = CalVals{1,2};
                delete(fig);


                return
            end

            % Delete figure after loading previous calibration data
            delete(fig);

            % Add new calibration data from Calibration_320Hz Arduino Code
            % Backup Values: Tare = 33659.41, Scale = 190656.68
            if strcmp(selection, "New Calibration")
                dlgtitle = "New Calibration Values";
                prompt = {'Tare Value From Arduino Calibration', 'Scale Value From Arduino Calibration'};
                fieldsize = [1 45; 1 45];
                definput = {'', ''};
                inputVals = str2double(inputdlg(prompt,dlgtitle,fieldsize,definput));

                % Return if user cancels
                if isempty(inputVals)
                    CalVals = readtable("Calibration.csv");
                    inputVals(1,1) = CalVals{1,1};
                    inputVals(2,1) = CalVals{1,2};
                    return
                end
                
                
                % Store Tare and Scale properties as previous values
                app.Tare = inputVals(1,1);
                app.Scale = inputVals(2,1);

                % Save new Tare and Scale values in excel sheet
                CalibrationValues = [app.Tare, app.Scale];
                writematrix(CalibrationValues, 'Calibration.csv')
                delete(fig);
                return
            end

            % Delete figure after loading previous calibration data
            delete(fig);

            % % Message directing user to prepare for tare by setting MT160
            % % to upright position
            % fig = uifigure;
            % fig.Position = [500, 500, 370, 220];
            % msg = "Set MT160 to upright position, remove any attachments from load cell.";
            % title = "Tare";
            % selection = uiconfirm(fig,msg,title, ...
            %     "Options",["Begin Tare","Cancel"], ...
            %     "DefaultOption",1,"CancelOption",2);
            %
            % % Return if user cancels
            % if strcmp(selection, "Cancel")
            %     return
            % end
            %
            % % Remove figure after MT160 is set vertical
            % delete(fig);
            %
            % % Take data to tare load cell
            % % Take 100 samples
            % z = 5000;
            % x = 1:1:z;
            % for j=1:1:z
            %     % read 100 load cell values and store to x
            %     x(j) = str2double(readline(app.serialObj));
            %     pause(1/1000);
            % end
            %
            % % Tare by taking the sum of all data points and divide by the
            % % number of samples
            % app.Tare = sum(x)./z;
            %
            % % Message to attach calibration weight after tare to get scale
            % prompt = {'Enter and attach calibration weight [kg]:'};
            % dlgtitle = 'Input';
            % fieldsize = [1 45];
            % definput = {'0.08007'};
            %
            % % Get known weight value from input by user
            % app.KnownWeight = str2double(inputdlg(prompt,dlgtitle,fieldsize,definput));
            %
            % % Take data to get scale factor for load cell
            % x = 1:1:z;
            % for j=1:1:z
            %     % Read in 100 samples from load cell and store in x
            %     x(j) = str2double(readline(app.serialObj));
            %     pause(1/1000);
            % end
            %
            % % Calculate scale by subtracting the sum of x by tare and
            % % dividing by known weight
            % app.Scale = (sum(x)./z - app.Tare)./app.KnownWeight;
            %
            % % Message to tell user that calibration has been complete
            % fig = uifigure;
            % fig.Position = [500, 500, 370, 220];
            % msg = "Calibration complete.";
            % title = "Calibration";
            % selection = uiconfirm(fig,msg,title, ...
            %     "Options",["Ok"], ...
            %     "DefaultOption",1);
            %
            % % Store tare and scale values to csv for future use
            % CalibrationValues = [app.Tare, app.Scale];
            % writematrix(CalibrationValues, 'Calibration.csv')

            % Delete message figure after ok selection by user
            delete(fig);

        end

        % Value changed function: FunctionGeneratorSwitch
        function FunctionGeneratorSwitchValueChanged(app, event)

            % This function updates the output state on the function
            % generator based on user input via a switch


            % Check if function generator visa is created
            if isempty(app.v)
                % if created, created, connect, and save the function
                % generator visa
                visaCode = 'USB0::0x09C4::0x0400::DG1D141701708::INSTR';
                app.v = visa('ni', visaCode);
            end

            % Check if user has turned on the function generator via the GUI
            % switch
            if strcmp(app.FunctionGeneratorSwitch.Value, 'On')

                % Open the function generator visa
                fopen(app.v);

                % Send the daq the following information in order:
                % Frequency
                % Amplitude
                % Offset: always set to 0
                s = sprintf('APPL:SIN %f,%f,%f\n',app.FrequencyEditField.Value,app.AmplitudeEditField.Value,0);

                % Delay fo a second to give time to pass info to function
                % generator
                t = timer;
                t.StartDelay(1);
                t.TimerFcn = @(~,~) fprintf(app.v, s);

                % Turn on the output of the function generator
                fprintf(app.v,'OUTP ON');

                % Close the function generator visa
                fclose(app.v);

                % Check to see if user set function generator switch to off
            elseif strcmp(app.FunctionGeneratorSwitch.Value, 'Off')
                % open the fuction generator visa
                fopen(app.v);
                % turn the fuction generator off
                fprintf(app.v,'OUTP OFF');
                % close the fuction generator visa
                fclose(app.v);
            end
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)

            % This function sends an updated frequency and amplitude value
            % when the user updates the frequency field input


            % Check if function generator visa is created
            if isempty(app.v)
                % if created, created, connect, and save the function
                % generator visa
                visaCode = 'USB0::0x09C4::0x0400::DG1D141701708::INSTR';
                app.v = visa('ni', visaCode);
            end

            % open the fuction generator visa
            fopen(app.v);
            % Send the daq the following information in order:
            % Frequency
            % Amplitude
            % Offset: always set to 0
            s = sprintf('APPL:SIN %f,%f,%f\n',app.FrequencyEditField.Value,app.AmplitudeEditField.Value,0);

            % Send request to the function generator
            fprintf(app.v, s);

            % close the fuction generator visa
            fclose(app.v);

        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)

            % This function sends an updated frequency and amplitude value
            % when the user updates the amplitude field input

            % Check if function generator visa is created
            if isempty(app.v)
                % if created, created, connect, and save the function
                % generator visa
                visaCode = 'USB0::0x09C4::0x0400::DG1D141701708::INSTR';
                app.v = visa('ni', visaCode);
            end

            % open the fuction generator visa
            fopen(app.v);
            % Send the daq the following information in order:
            % Frequency
            % Amplitude
            % Offset: always set to 0
            s = sprintf('APPL:SIN %f,%f,%f\n',app.FrequencyEditField.Value,app.AmplitudeEditField.Value,0);

            % Send request to the function generator
            fprintf(app.v, s);

            % close the fuction generator visa
            fclose(app.v);


        end

        % Button pushed function: InerterSpecsButton
        function InerterSpecsButtonPushed(app, event)

            % This function allows the user to input the inerter specs
            % specific to the type of inerter installed on the testing rig.
            % Add more cases if other inerter types need to be tested.

            % Switch case for different options in drop down menu for each
            % inerter type
            switch app.InerterTypeDropDown.Value

                % User inputs for each of the specs involved in the
                % gear inerter b value calculation
                case 'Gear Inerter'
                    dlgtitle = "Gear Inerter Spec Input";
                    prompt = {'Outer Gear Radius [m]', 'Inner Gear Radius [m]', 'Inerter Mass [kg]', 'Number of Gears'};
                    fieldsize = [1 45; 1 45; 1 45; 1 45];
                    definput = {'', '', '', ''};
                    input = str2double(inputdlg(prompt,dlgtitle,fieldsize,definput));

                    % Stores user spec inputs to properties used in
                    % theoretical inertance calculations
                    app.OuterRadii = input(1,1);        % Outer gear radius (m)
                    app.InnerRadii = input(2,1);        % Inner gear radius (m)
                    app.InerterMass = input(3,1);       % Mass of inerter (kg)
                    app.numGears = input(4,1);          % Number of gears in train
                    app.InerterType = 1;

                    % User inputs for each of the specs involved in the
                    % Chad inerter b value calculation
                case 'Chad Inerter'
                    prompt = {'Mass of Rack [kg]', 'Moment of Inertia [kg*m^2]', 'Gear Radius [m]'};
                    dlgtitle = "Chad Inerter Spec Input";
                    fieldsize = [1 45; 1 45; 1 45];
                    definput = {'0.22', '0.000023257', '0.01552'};
                    input = str2double(inputdlg(prompt,dlgtitle,fieldsize,definput));

                    % Stores user spec inputs to properties used in
                    % theoretical inertance calculations
                    app.MassRack = input(1,1);           % Outer gear radius (m)
                    app.MomentInertia = input(2,1);      % Inner gear radius (m)
                    app.GearRadius = input(3,1);         % Mass of inerter (kg)
                    app.InerterType = 2;

                    % User inputs for each of the specs involved in the
                    % Shear inerter b value calculation
                case 'Shear Inerter'

                    % Add specs here after determining the theoretical
                    % equation for the shear inerter

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AccPlot = uiaxes(app.UIFigure);
            title(app.AccPlot, 'Acceleration Data')
            xlabel(app.AccPlot, 'Time (s)')
            ylabel(app.AccPlot, 'Acceleration (m/s^2)')
            zlabel(app.AccPlot, 'Z')
            app.ForcePlot = uiaxes(app.UIFigure);
            title(app.ForcePlot, 'Force Data')
            xlabel(app.ForcePlot, 'Time (s)')
            ylabel(app.ForcePlot, 'Force (N)')
            zlabel(app.ForcePlot, 'Z')
            app.FourierTransformPlot = uiaxes(app.UIFigure);
            title(app.FourierTransformPlot, 'Fourier Transform')
            xlabel(app.FourierTransformPlot, 'Frequency (Hz)')
            ylabel(app.FourierTransformPlot, 'Magnitude')
            app.PerformInertanceCalculationButton = uibutton(app.UIFigure, 'push');
            app.PerformInertanceCalculationButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateInertance, true);
            app.PerformInertanceCalculationButton.Text = {'Perform'; 'Inertance'; 'Calculation'};
            app.ExperimentalInertanceLabel = uilabel(app.UIFigure);
            app.ExperimentalInertanceLabel.Text = {'Experimental'; 'Inertance (kg)'};
            app.ExperimentalInertanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartDataCollectionButton = uibutton(app.UIFigure, 'state');
            app.StartDataCollectionButton.ValueChangedFcn = createCallbackFcn(app, @StartDataCollectionButtonValueChanged, true);
            app.StartDataCollectionButton.Text = 'Start Data Collection';
            app.TimesSliderLabel = uilabel(app.UIFigure);
            app.TimesSliderLabel.Text = 'Time (s)';
            app.TimesSlider = uislider(app.UIFigure);
            app.TimesSlider.Limits = [1 10];
            app.TimesSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.TimesSlider.Value = 1;
            app.CalculatedInertancekgEditFieldLabel_2 = uilabel(app.UIFigure);
            app.CalculatedInertancekgEditFieldLabel_2.Text = {'Calculated'; 'Inertance (kg)'};
            app.CalculatedInertanceEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CalculatedInertanceEditField_2.Editable = 'off';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.CalibrateLoadCellButton = uibutton(app.UIFigure, 'push');
            app.CalibrateLoadCellButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateLoadCell, true);
            app.CalibrateLoadCellButton.Text = 'Calibrate Load Cell';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.FunctionGeneratorSwitchLabel = uilabel(app.UIFigure);
            app.FunctionGeneratorSwitchLabel.Text = 'Function Generator';
            app.FunctionGeneratorSwitch = uiswitch(app.UIFigure, 'slider');
            app.FunctionGeneratorSwitch.ValueChangedFcn = createCallbackFcn(app, @FunctionGeneratorSwitchValueChanged, true);
            app.InerterTypeDropDownLabel = uilabel(app.UIFigure);
            app.InerterTypeDropDownLabel.Text = 'Inerter Type';
            app.InerterTypeDropDown = uidropdown(app.UIFigure);
            app.InerterTypeDropDown.Items = {'Gear Inerter', 'Chad Inerter', 'Shear Inerter'};
            app.InerterTypeDropDown.Value = 'Gear Inerter';
            app.InerterSpecsButton = uibutton(app.UIFigure, 'push');
            app.InerterSpecsButton.ButtonPushedFcn = createCallbackFcn(app, @InerterSpecsButtonPushed, true);
            app.InerterSpecsButton.Text = 'Inerter Specs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InerterAppSerial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end